classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        CheckBox_2  matlab.ui.control.CheckBox
        CheckBox    matlab.ui.control.CheckBox
        ListBox     matlab.ui.control.ListBox
        Label       matlab.ui.control.Label
        Button_2    matlab.ui.control.Button
        Button      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if strcmp(app.ListBox.Value, 'Эллиптический')
                typeEnum = 0;
            elseif strcmp(app.ListBox.Value, 'Сегментный')
                typeEnum = 1;
            end
            imbalanceBool = app.CheckBox.Value;
            impulseBool = app.CheckBox_2.Value;
            Moving_graph(typeEnum,300,imbalanceBool,impulseBool)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if strcmp(app.ListBox.Value, 'Эллиптический')
                typeEnum = 0;
            elseif strcmp(app.ListBox.Value, 'Сегментный')
                typeEnum = 1;
            end
            imbalanceBool = app.CheckBox.Value;
            Border_graph(typeEnum,300,imbalanceBool)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Перемещения';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = {'Область '; 'устойчивости'};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Подшипник';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Эллиптический', 'Сегментный'};
            app.ListBox.Value = 'Эллиптический';
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = 'Дисбаланс';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = 'Возмущение';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end